classdef PPGFeat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PPGFeatUIFigure             matlab.ui.Figure
        canddpresentEditField       matlab.ui.control.NumericEditField
        canddpresentEditFieldLabel  matlab.ui.control.Label
        fEditField                  matlab.ui.control.NumericEditField
        fEditFieldLabel             matlab.ui.control.Label
        eEditField                  matlab.ui.control.NumericEditField
        eEditFieldLabel             matlab.ui.control.Label
        dEditField                  matlab.ui.control.NumericEditField
        dEditFieldLabel             matlab.ui.control.Label
        cEditField                  matlab.ui.control.NumericEditField
        cmLabel                     matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        aEditField                  matlab.ui.control.NumericEditField
        aEditFieldLabel             matlab.ui.control.Label
        Yes0NoLabel                 matlab.ui.control.Label
        uEditField                  matlab.ui.control.NumericEditField
        wLabel                      matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        zLabel                      matlab.ui.control.Label
        xLabel                      matlab.ui.control.Label
        vEditField                  matlab.ui.control.NumericEditField
        wEditField                  matlab.ui.control.NumericEditField
        yLabel                      matlab.ui.control.Label
        OnEditField                 matlab.ui.control.NumericEditField
        onLabel                     matlab.ui.control.Label
        SpEditField                 matlab.ui.control.NumericEditField
        DLabel                      matlab.ui.control.Label
        DnEditField                 matlab.ui.control.NumericEditField
        dnLabel                     matlab.ui.control.Label
        DpEditField                 matlab.ui.control.NumericEditField
        SLabel                      matlab.ui.control.Label
        DataTextArea                matlab.ui.control.TextArea
        DataTextAreaLabel           matlab.ui.control.Label
        IDEditField                 matlab.ui.control.NumericEditField
        IDLabel                     matlab.ui.control.Label
        Min2EditField               matlab.ui.control.NumericEditField
        Min2EditFieldLabel          matlab.ui.control.Label
        Min1EditFieldLabel          matlab.ui.control.Label
        Min1EditField               matlab.ui.control.NumericEditField
        Panel                       matlab.ui.container.Panel
        LoadFolderButton            matlab.ui.control.Button
        PlotpressedLamp             matlab.ui.control.Lamp
        PlotpressedLampLabel        matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label                       matlab.ui.control.Label
        SsqiEditField               matlab.ui.control.NumericEditField
        SsqiEditFieldLabel          matlab.ui.control.Label
        GenerateoutputButton        matlab.ui.control.Button
        GotonextwaveformLabel       matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel  matlab.ui.control.Label
        UpdateButton                matlab.ui.control.Button
        Plotallwaveformsusingmin1andmin2Label  matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        unfit                       matlab.ui.control.Lamp
        acceptable                  matlab.ui.control.Lamp
        good                        matlab.ui.control.Lamp
        SQI082Good03SQI081AcceptableSQI03UnfitLabel  matlab.ui.control.Label
        CSVwithSsqianddataIDLabel   matlab.ui.control.Label
        LoadSsqiButton              matlab.ui.control.Button
        RAWPPGdataLabel             matlab.ui.control.Label
        LoadFileButton              matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        sLabel                      matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        SegmentCheck                matlab.ui.control.CheckBox
        SegmentEditField            matlab.ui.control.NumericEditField
        SegmentEditFieldLabel       matlab.ui.control.Label
        ResampleCheck               matlab.ui.control.CheckBox
        ResampleEditField           matlab.ui.control.NumericEditField
        ResampleEditFieldLabel      matlab.ui.control.Label
        EnterSamplingfreqfilterfreqLabel  matlab.ui.control.Label
        FHEditField                 matlab.ui.control.NumericEditField
        FHEditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        F1Label                     matlab.ui.control.Label
        FsHzEditField               matlab.ui.control.NumericEditField
        FsHzEditFieldLabel          matlab.ui.control.Label
        SkipforbadSsqiButton        matlab.ui.control.Button
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        PPGAnalObject
    end

    methods (Access = private)

        function UpdateSsqi(app)
            app.SsqiEditField.Value = app.PPGAnalObject.Ssqi(app.PPGAnalObject.entry_idx,1);
            app.IDEditField.Value = app.PPGAnalObject.Sub_ID;

            if app.SsqiEditField.Value > 0.8
                app.Lamp.Color = 'g';
            end

            if (app.SsqiEditField.Value < 0.8) && (app.SsqiEditField.Value >= 0.3)
                app.Lamp.Color = 'y';

            end

            if app.SsqiEditField.Value < 0.3
                app.Lamp.Color = 'r';
            end

            app.PlotpressedLamp.Color = 'r';
        end

        function PlotPPG(app)
            app.UpdateSsqi();

            plot(app.UIAxes4, app.PPGAnalObject.loaded_data(app.PPGAnalObject.data_idx,:))  %Plot first RAW waveform

            %show data numbr in the text area 
            Dat1 = num2str(app.PPGAnalObject.size_data(1) * app.PPGAnalObject.num_seg);
            Dat2 = num2str(app.PPGAnalObject.total_seg_idx);
            Dat = Dat2 + " of " + Dat1;
            app.DataTextArea.Value = Dat;

            %plot the filtered PPG and show the maxima and minima
            filtered_data = app.PPGAnalObject.PPG_filtered(app.PPGAnalObject.total_seg_idx ,:);
            PPG = plot(app.UIAxes, filtered_data ); %Plot filtered PPG
            %addToolbarExplorationButtons(PPG);
            grid(app.UIAxes, "on")

            [PPG_max, PPG_min] = app.PPGAnalObject.FindSegMaxMin();

            index_PPG_max = find(PPG_max); %index of maxima points
            value_PPG_max = filtered_data(PPG_max);  %value at maxima point

            index_PPG_min = find( PPG_min) ; %index of maxima points
            value_PPG_min = filtered_data( PPG_min) ;  %value at maxima point


            if (~isempty(index_PPG_max))
                %ax = gca;
                %chart = ax.Children();
                for ii = 1:length(index_PPG_max)
                    %datatip(PPG,index_PPG_max(ii),value_PPG_max(ii),'FontSize',8);
                end
            end


            if (~isempty(index_PPG_min))
%                 ax = gca;
%                 chart = ax.Children();
                for ij = 1:length(index_PPG_min)
                    %datatip(PPG,index_PPG_min(ij),value_PPG_min(ij),'FontSize',8,'Location','southeast');
                end
            end

            %read min1 and min from ppg waveform and show in the edit field

            % Set the threshold value for comparing the minima indices
            threshold = 40 * app.PPGAnalObject.RFs/100;
            
            % Loop over all minima indices
            for i = 1:length(index_PPG_min)-1
                
                % Check if the next minimum index is greater than the current one by
                % the threshold value
                if index_PPG_min(i+1) > index_PPG_min(i) + threshold
                    % If yes, check if there is any maximum index between the two minima
                    maxima_between_minima = index_PPG_max(index_PPG_max > index_PPG_min(i) & index_PPG_max < index_PPG_min(i+1));
                    if ~isempty(maxima_between_minima)
                        
                        % Add any other code you need to perform here
                    app.Min1EditField.Value = index_PPG_min(i);
                    app.Min2EditField.Value = index_PPG_min(i+1);
                    break
                    end
                end
            end

            datatip(PPG, app.Min1EditField.Value,filtered_data(app.Min1EditField.Value),'FontSize',8);
            datatip(PPG, app.Min2EditField.Value,filtered_data(app.Min2EditField.Value),'FontSize',8);
        end
        
        function PlotFiducial(app, PPGAnal)
            %plot the PPG segment
            P_seg = plot(app.UIAxes2,PPGAnal.seg);
            grid(app.UIAxes2,"on")

            line(app.UIAxes2,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            for i = 1:4
                if (PPGAnal.OnSpDnDp_time(i) ~= 0)
                    datatip(P_seg, PPGAnal.OnSpDnDp_time(i),PPGAnal.OnSpDnDp(i),'FontSize',8);
                end
            end

            %plot VPG
            P_vpg = plot(app.UIAxes6, PPGAnal.VPG);
            grid(app.UIAxes6,"on")

            line(app.UIAxes6,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment

            for i = 1:4
                if (PPGAnal.uxvw_time(i) ~= 0)
                    datatip(P_vpg, PPGAnal.uxvw_time(i),PPGAnal.uxvw(i),'FontSize',8);
                end
            end

            %plot APG
            P_apg = plot(app.UIAxes5, PPGAnal.APG);
            grid(app.UIAxes5,"on")

            line(app.UIAxes5,[0 length(PPGAnal.seg)],[0 0],'Color','red','LineStyle','--') %zero crossing line on segment    
        
            for i = 1:5
                if (PPGAnal.abcde_time(i) ~= 0)
                    datatip(P_apg, PPGAnal.abcde_time(i),PPGAnal.abcde(i),'FontSize',8);
                end
            end

            if (PPGAnal.f ~= 0)
                datatip(P_apg, PPGAnal.f, PPGAnal.APG(PPGAnal.f),'FontSize',8);
            end
        
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PPGAnalObject = ppg_anal;
            PPGFeatUIFigureThemeChanged(app);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)

            if (app.ResampleCheck.Value == true)
                res = app.PPGAnalObject.LoadPPG(app.FsHzEditField.Value, app.ResampleEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            else
                res = app.PPGAnalObject.LoadPPG(app.FsHzEditField.Value, app.FsHzEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            end
            
            if (res)
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                app.PlotPPG();
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            app.PlotpressedLamp.Color = 'g'; %to show user that the plot button has not been pressed

            result = PPGAnal.CalculateFiducial(app.Min1EditField.Value, app.Min2EditField.Value);

            if (~result)
                msgbox('Could not calcualte all fiducial points for segment', 'Error', 'error')
            end

            %Update fields after with ppg_anal values
            app.OnEditField.Value = PPGAnal.on;
            app.SpEditField.Value = PPGAnal.sp;
            app.DnEditField.Value = PPGAnal.dn;
            app.DpEditField.Value = PPGAnal.dp;

            app.uEditField.Value = PPGAnal.u;
            app.xEditField.Value = PPGAnal.x;
            app.vEditField.Value = PPGAnal.v;
            app.wEditField.Value = PPGAnal.w;

            app.aEditField.Value = PPGAnal.a;
            app.bEditField.Value = PPGAnal.b;
            app.cEditField.Value = PPGAnal.c;
            app.dEditField.Value = PPGAnal.d;
            app.eEditField.Value = PPGAnal.e;
            app.fEditField.Value = PPGAnal.f;

            app.canddpresentEditField.Value = PPGAnal.c_d_pres;

            app.PlotFiducial(PPGAnal);

            %plot overall PPG segments
            hold(app.UIAxes3,"on")
            plot(app.UIAxes3, PPGAnal.seg)
            hold(app.UIAxes3,"off")
        end

        % Button pushed function: GenerateoutputButton
        function GenerateoutputButtonPushed(app, event)
            app.PPGAnalObject.GenerateOutput();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if (app.PPGAnalObject.Next())

                %alert on 2nd last data
                if (app.PPGAnalObject.entry_idx+1 == app.PPGAnalObject.size_data(1))
                msgbox("This is second last data","Alert","warn");
                end
    
                %autosave onlast data
                if (app.PPGAnalObject.entry_idx > app.PPGAnalObject.size_data(1))
                msgbox("Auto saving","Alert","warn");
                GenerateoutputButtonPushed(app, event)
                ExitButtonPushed(app, event)
                end
    
                app.PlotPPG();
            end
        end

        % Button pushed function: LoadSsqiButton
        function LoadSsqiButtonPushed(app, event)
            app.PPGAnalObject.LoadSsqi();
            
            app.UpdateSsqi();
        end

        % Button pushed function: SkipforbadSsqiButton
        function SkipforbadSsqiButtonPushed(app, event)
            NextButtonPushed(app, event);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            PPGAnal = app.PPGAnalObject;

            %%Value update for PPG
            PPGAnal.OnSpDnDp = [PPGAnal.seg(app.OnEditField.Value) PPGAnal.seg(app.SpEditField.Value) PPGAnal.seg(app.DnEditField.Value) PPGAnal.seg(app.DpEditField.Value)];

            %Value update for VPG
            PPGAnal.uxvw = [PPGAnal.VPG(app.uEditField.Value) PPGAnal.VPG(app.xEditField.Value) PPGAnal.VPG(app.vEditField.Value) PPGAnal.VPG(app.wEditField.Value)];

            %value update for APG
            PPGAnal.c_d_APG(PPGAnal.total_seg_idx) = app.canddpresentEditField.Value;
            PPGAnal.abcde = [PPGAnal.APG(app.aEditField.Value) PPGAnal.APG(app.bEditField.Value) PPGAnal.APG(app.cEditField.Value) PPGAnal.APG(app.dEditField.Value) PPGAnal.APG(app.eEditField.Value)];
            
            %time variables of all waveform
            PPGAnal.OnSpDnDp_time  = [app.OnEditField.Value app.SpEditField.Value app.DnEditField.Value app.DpEditField.Value];
            PPGAnal.uxvw_time  = [app.uEditField.Value app.xEditField.Value app.vEditField.Value app.wEditField.Value];
            PPGAnal.abcde_time = [app.aEditField.Value app.bEditField.Value app.cEditField.Value app.dEditField.Value app.eEditField.Value];
            
            %for f values of APG
            F_Value = PPGAnal.APG(app.fEditField.Value);
            F_t = app.fEditField.Value;
            %for On+1 values of PPG
            O_next_t = (app.Min2EditField.Value - app.Min1EditField.Value);
            %disp(O_next)
            O_next = PPGAnal.seg(O_next_t);
            %feature table
            PPGAnal.feature(PPGAnal.total_seg_idx,:) = [PPGAnal.OnSpDnDp O_next PPGAnal.uxvw PPGAnal.abcde F_Value PPGAnal.OnSpDnDp_time O_next_t PPGAnal.uxvw_time PPGAnal.abcde_time F_t];
            %disp(app.feature)

            %update the figues
            app.PlotFiducial(PPGAnal);
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            if (app.ResampleCheck.Value == true)
                res = app.PPGAnalObject.LoadDirectory(app.FsHzEditField.Value, app.ResampleEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            else
                res = app.PPGAnalObject.LoadDirectory(app.FsHzEditField.Value, app.FsHzEditField.Value, ...
                    app.FLEditField.Value, app.FHEditField.Value, app.SegmentCheck.Value, ceil(app.SegmentEditField.Value * 1000));
            end
            if (res)
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
                cla(app.UIAxes6);
                app.PlotPPG();
            end
        end

        % Callback function
        function PPGFeatUIFigureThemeChanged(app, event)
            app_color = [0.94 0.94 0.94];
            panel_color = [0.8 0.8 0.8];
            panel2_color = [0.65 0.65 0.65];

            if app.PPGFeatUIFigure.Theme.BaseColorStyle == "light"
                app.PPGFeatUIFigure.Color = app_color;
                app.Panel.BackgroundColor = panel_color;
                app.Panel_2.BackgroundColor = panel2_color;
            elseif app.PPGFeatUIFigure.Theme.BaseColorStyle == "dark"
                app.PPGFeatUIFigure.Color = fliplightness(app_color);
                app.Panel.BackgroundColor = fliplightness(panel_color);
                app.Panel_2.BackgroundColor = fliplightness(panel2_color);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PPGFeatUIFigure = uifigure('Visible', 'off');
            app.PPGFeatUIFigure.Name = 'PPGFeat';
            app.UIAxes = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes, 'Filtered PPG')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Normalized values')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes3, 'PPG Segments')
            xlabel(app.UIAxes3, 'Samples')
            ylabel(app.UIAxes3, 'Normalized Values')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes4, 'RAW PPG')
            xlabel(app.UIAxes4, 'Samples')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes2, 'PPG Segment')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes6 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes6, 'VPG')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.PPGFeatUIFigure);
            title(app.UIAxes5, 'APG')
            zlabel(app.UIAxes5, 'Z')
            app.Panel = uipanel(app.PPGFeatUIFigure);
            app.SkipforbadSsqiButton = uibutton(app.Panel, 'push');
            app.SkipforbadSsqiButton.ButtonPushedFcn = createCallbackFcn(app, @SkipforbadSsqiButtonPushed, true);
            app.SkipforbadSsqiButton.Text = 'Skip for bad Ssqi';
            app.Panel_2 = uipanel(app.Panel);
            app.FsHzEditFieldLabel = uilabel(app.Panel_2);
            app.FsHzEditFieldLabel.Text = 'Fs  (Hz)';
            app.FsHzEditField = uieditfield(app.Panel_2, 'numeric');
            app.FsHzEditField.Value = 1000;
            app.F1Label = uilabel(app.Panel_2);
            app.F1Label.Text = 'FL';
            app.FLEditField = uieditfield(app.Panel_2, 'numeric');
            app.FLEditField.Value = 0.4;
            app.FHEditFieldLabel = uilabel(app.Panel_2);
            app.FHEditFieldLabel.Text = 'FH';
            app.FHEditField = uieditfield(app.Panel_2, 'numeric');
            app.FHEditField.Value = 8;
            app.EnterSamplingfreqfilterfreqLabel = uilabel(app.Panel_2);
            app.EnterSamplingfreqfilterfreqLabel.Text = '  Enter Sampling freq & filter freq';
            app.ResampleEditFieldLabel = uilabel(app.Panel_2);
            app.ResampleEditFieldLabel.Text = 'Resample';
            app.ResampleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ResampleEditField.Value = 1000;
            app.ResampleCheck = uicheckbox(app.Panel_2);
            app.ResampleCheck.Text = '';
            app.SegmentEditFieldLabel = uilabel(app.Panel_2);
            app.SegmentEditFieldLabel.Text = 'Segment';
            app.SegmentEditField = uieditfield(app.Panel_2, 'numeric');
            app.SegmentEditField.Value = 10;
            app.SegmentCheck = uicheckbox(app.Panel_2);
            app.SegmentCheck.Text = '';
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Text = 'Hz';
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = 's';
            app.LoadFileButton = uibutton(app.Panel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.RAWPPGdataLabel = uilabel(app.Panel);
            app.RAWPPGdataLabel.Text = 'RAW PPG data';
            app.LoadSsqiButton = uibutton(app.Panel, 'push');
            app.LoadSsqiButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSsqiButtonPushed, true);
            app.LoadSsqiButton.Text = 'Load Ssqi';
            app.CSVwithSsqianddataIDLabel = uilabel(app.Panel);
            app.CSVwithSsqianddataIDLabel.Text = 'CSV with Ssqi and data ID';
            app.SQI082Good03SQI081AcceptableSQI03UnfitLabel = uilabel(app.Panel);
            app.SQI082Good03SQI081AcceptableSQI03UnfitLabel.Text = {'SQI > 0.82       = Good'; '0.3<SQI<0.81  = Acceptable'; 'SQI <0.3          = Unfit'};
            app.good = uilamp(app.Panel);
            app.acceptable = uilamp(app.Panel);
            app.unfit = uilamp(app.Panel);
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Plotallwaveformsusingmin1andmin2Label = uilabel(app.Panel);
            app.Plotallwaveformsusingmin1andmin2Label.Text = {'Plot all waveforms using '; 'min1 and min2'};
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel = uilabel(app.Panel);
            app.UpdatefeaturepointsusingmodifieduserdefinedvaluesLabel.Text = {'Update feature points using'; 'modified user defined values'};
            app.NextButton = uibutton(app.Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.GotonextwaveformLabel = uilabel(app.Panel);
            app.GotonextwaveformLabel.Text = 'Go to next waveform ';
            app.GenerateoutputButton = uibutton(app.Panel, 'push');
            app.GenerateoutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateoutputButtonPushed, true);
            app.GenerateoutputButton.Text = 'Generate output';
            app.SsqiEditFieldLabel = uilabel(app.Panel);
            app.SsqiEditFieldLabel.Text = 'Ssqi';
            app.SsqiEditField = uieditfield(app.Panel, 'numeric');
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.Panel);
            app.PlotpressedLampLabel = uilabel(app.Panel);
            app.PlotpressedLampLabel.Text = 'Plot pressed';
            app.PlotpressedLamp = uilamp(app.Panel);
            app.LoadFolderButton = uibutton(app.Panel, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.Min1EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Min1EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min1EditFieldLabel.Text = 'Min1';
            app.Min2EditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.Min2EditFieldLabel.Text = 'Min2';
            app.Min2EditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.IDLabel = uilabel(app.PPGFeatUIFigure);
            app.IDLabel.Text = 'ID ';
            app.IDEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.IDEditField.Editable = 'off';
            app.DataTextAreaLabel = uilabel(app.PPGFeatUIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.PPGFeatUIFigure);
            app.DataTextArea.Editable = 'off';
            app.SLabel = uilabel(app.PPGFeatUIFigure);
            app.SLabel.Text = 'dp';
            app.DpEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.dnLabel = uilabel(app.PPGFeatUIFigure);
            app.dnLabel.Text = 'dn';
            app.DnEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.DLabel = uilabel(app.PPGFeatUIFigure);
            app.DLabel.Text = 'sp';
            app.SpEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.onLabel = uilabel(app.PPGFeatUIFigure);
            app.onLabel.Text = 'on';
            app.OnEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.yLabel = uilabel(app.PPGFeatUIFigure);
            app.yLabel.Text = 'v';
            app.wEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.vEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.xLabel = uilabel(app.PPGFeatUIFigure);
            app.xLabel.Text = 'x';
            app.zLabel = uilabel(app.PPGFeatUIFigure);
            app.zLabel.Text = 'w';
            app.xEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.wLabel = uilabel(app.PPGFeatUIFigure);
            app.wLabel.Text = 'u';
            app.uEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.Yes0NoLabel = uilabel(app.PPGFeatUIFigure);
            app.Yes0NoLabel.Text = '1: Yes, 0: No';
            app.aEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.cmLabel = uilabel(app.PPGFeatUIFigure);
            app.cmLabel.Text = 'c ';
            app.cEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.dEditFieldLabel.Text = 'd ';
            app.dEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.eEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.fEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            app.canddpresentEditFieldLabel = uilabel(app.PPGFeatUIFigure);
            app.canddpresentEditFieldLabel.Text = 'c and d present ';
            app.canddpresentEditField = uieditfield(app.PPGFeatUIFigure, 'numeric');
            % Show the figure after all components are created
            app.PPGFeatUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPGFeat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PPGFeatUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PPGFeatUIFigure)
        end
    end
end